classdef PIPET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        LoadfileMenu                    matlab.ui.container.Menu
        LoadfilelistMenu                matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DecimateTab                     matlab.ui.container.Tab
        DownsamplingfactorEditFieldLabel  matlab.ui.control.Label
        DownsamplingfactorEditField     matlab.ui.control.NumericEditField
        NtapsEditFieldLabel             matlab.ui.control.Label
        NtapsEditField                  matlab.ui.control.NumericEditField
        DesignButton                    matlab.ui.control.Button
        OutputFsEditFieldLabel          matlab.ui.control.Label
        OutputFsEditField               matlab.ui.control.NumericEditField
        SelectinputButtonGroup          matlab.ui.container.ButtonGroup
        DFactorButton                   matlab.ui.control.RadioButton
        OutputFsButton                  matlab.ui.control.RadioButton
        NtapsEditField_3Label           matlab.ui.control.Label
        NtapsEditField_3                matlab.ui.control.NumericEditField
        HPfilterTab                     matlab.ui.container.Tab
        FcEditFieldLabel                matlab.ui.control.Label
        FcEditField                     matlab.ui.control.NumericEditField
        NtapsEditField_2Label           matlab.ui.control.Label
        NtapsEditField_2                matlab.ui.control.NumericEditField
        DesignButton_2                  matlab.ui.control.Button
        DesignworksonlywithdfactordecimationLabel  matlab.ui.control.Label
        RemovebadCHTab                  matlab.ui.container.Tab
        FINDButton                      matlab.ui.control.Button
        LOADButton                      matlab.ui.control.Button
        FindbadchannelsforfilesLabel    matlab.ui.control.Label
        LoadtablewithbadCHinfoLabel     matlab.ui.control.Label
        SaveintoseparatefileforlaterloadingCheckBox  matlab.ui.control.CheckBox
        ORLabel                         matlab.ui.control.Label
        ReferenceTab                    matlab.ui.container.Tab
        ChoosenewreferenceButtonGroup   matlab.ui.container.ButtonGroup
        CommonrefButton                 matlab.ui.control.RadioButton
        CommonaverageButton             matlab.ui.control.RadioButton
        LinkedelectrodesButton          matlab.ui.control.RadioButton
        ElectrodenumberSpinnerLabel     matlab.ui.control.Label
        ElectrodenumberSpinner          matlab.ui.control.Spinner
        ElectrodenumberSpinner_2        matlab.ui.control.Spinner
        ElectrodenumberSpinner_3        matlab.ui.control.Spinner
        defmastoidLabel                 matlab.ui.control.Label
        PlotButton_3                    matlab.ui.control.Button
        OldreferenceTextAreaLabel       matlab.ui.control.Label
        OldreferenceTextArea            matlab.ui.control.TextArea
        PlotresultsCheckBox_2           matlab.ui.control.CheckBox
        InterpolatebadCHTab             matlab.ui.container.Tab
        NeighbourapproachButtonGroup    matlab.ui.container.ButtonGroup
        weightedButton                  matlab.ui.control.RadioButton
        averageButton                   matlab.ui.control.RadioButton
        splineButton                    matlab.ui.control.RadioButton
        slapButton                      matlab.ui.control.RadioButton
        sphericalLabel                  matlab.ui.control.Label
        surfacelaplacianLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NeighbourappButtonGroup         matlab.ui.container.ButtonGroup
        UseprecalculatedforegionlyButton  matlab.ui.control.RadioButton
        recalculateButton               matlab.ui.control.RadioButton
        NeighbourdistancemmSpinnerLabel  matlab.ui.control.Label
        NeighbourdistancemmSpinner      matlab.ui.control.Spinner
        PlotButton                      matlab.ui.control.Button
        PlotresultsCheckBox             matlab.ui.control.CheckBox
        DespikeTab                      matlab.ui.container.Tab
        ThisstepshouldberunseparatelyLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        RemovetrendTab                  matlab.ui.container.Tab
        PolynomialdegreeEditFieldLabel  matlab.ui.control.Label
        PolynomialdegreeEditField       matlab.ui.control.NumericEditField
        meanremoval1lineartrendLabel    matlab.ui.control.Label
        PlotresultsCheckBox_3           matlab.ui.control.CheckBox
        ICATab                          matlab.ui.container.Tab
        NumberofICEditFieldLabel        matlab.ui.control.Label
        NumberofICEditField             matlab.ui.control.NumericEditField
        LasteigenvalueEditFieldLabel    matlab.ui.control.Label
        LasteigenvalueEditField         matlab.ui.control.NumericEditField
        ApproachDropDownLabel           matlab.ui.control.Label
        ApproachDropDown                matlab.ui.control.DropDown
        NonlinearityDropDownLabel       matlab.ui.control.Label
        NonlinearityDropDown            matlab.ui.control.DropDown
        PlotresultsCheckBox_4           matlab.ui.control.CheckBox
        NoteifdataranknotadjustedghostingofICswillhappenLabel  matlab.ui.control.Label
        PlotprogressCheckBox            matlab.ui.control.CheckBox
        RemovebadICTab                  matlab.ui.container.Tab
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RemoveascalculatedButton        matlab.ui.control.RadioButton
        LoadfileswithcomputedICsButton  matlab.ui.control.RadioButton
        LOADButton_2                    matlab.ui.control.Button
        PlotresultsCheckBox_5           matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Lamp                            matlab.ui.control.Lamp
        Tree                            matlab.ui.container.Tree
        DecimateNode                    matlab.ui.container.TreeNode
        HPfilterNode                    matlab.ui.container.TreeNode
        RemovebadCHNode                 matlab.ui.container.TreeNode
        ReferenceNode_2                 matlab.ui.container.TreeNode
        InterpolatebadCHNode            matlab.ui.container.TreeNode
        DespikeNode                     matlab.ui.container.TreeNode
        RemovetrendNode                 matlab.ui.container.TreeNode
        ICANode                         matlab.ui.container.TreeNode
        RemovebadICNode                 matlab.ui.container.TreeNode
        ChooseoperationsLabel           matlab.ui.control.Label
        PlotButton_2                    matlab.ui.control.Button
        MultiselectholdctrlLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        data % Description
        datalist
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadfileMenu
        function LoadfileMenuSelected(app, event)
            
            app.Lamp.Color=[1 0 0]
            [file,path,index]=uigetfile({'*.dat';'*.mat'},'Choose file','/DATA/multimodal/EEG/');
            
       
            if ~isempty(file) && contains(file,'.mat')
                load([path file])
                app.data = data;
                app.datalist = app.data;
                app.Lamp.Color=[0 1 0]
                app.TextArea.Value = {sprintf('Current srate= %i Hz, Data dimensions: %i, %i, AUX channels: %i ',app.data.fsample, size(app.data.trial{1},1),size(app.data.trial{1},2), size(app.data.trial{1},1)-length(app.data.eeginds))} 

            elseif ~isempty(file) && contains(file,'.dat')
                [raw,srate]= load_eeg([path file]);
                app.data=initialize_vars_ft(raw,srate)
                app.data.eeginds = find(~strcmp(app.data.label,'ECG'));
                app.data.eeginds = find(~contains(app.data.label(app.data.eeginds),'CW'));
                fname = strsplit(file,'.dat');fname=fname{1};
                app.data.fname = fname;           
                
                app.TextArea.Value = {sprintf('Current srate= %i Hz, Data dimensions: %i, %i, AUX channels: %i ',app.data.fsample, size(app.data.trial{1},1),size(app.data.trial{1},2), size(app.data.trial{1},1)-length(app.data.eeginds))} 
                app.OldreferenceTextArea.Value = string(find(~app.data.trial{1}(:,1)))             
                % ICA editfields
                app.LasteigenvalueEditField.Value = length(app.data.eeginds);
                app.NumberofICEditField.Value = length(app.data.eeginds);
             
                app.Lamp.Color=[0 1 0]
                app.datalist = app.data;
            else
                app.Lamp.Color=[0 1 0]
            end

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % MAIN FUNCTION
            try
                
                app.Lamp.Color=[1 0 0]
                
                
                %% Destination folder for saving
                if length(app.datalist)==1
                    filter = {'*.mat';'*.dat'};
                    [file, path] = uiputfile(filter,'Save file as | filetype (.mat or .dat)',[app.datalist.fname '_PIPET' '.mat']);
                    savepath = [path file];
                else
                    filter = {'*.mat';'*.dat'};
                    [file, path] = uiputfile(filter,'Choose destination folder and group name | filetype (.mat or .dat)',['groupname' '_PIPET' '.mat']);
                    savepath = [path file];
                    
                end


                %% 1. Check which operations to run & initialise some variables
                
            
                operations={app.Tree.SelectedNodes.Text};
                
                for i=1:length(app.datalist)
                    app.data = app.datalist(i);
                    if ~isfield(app.data,'preprocessing')
                        %if ~isfield(app.data.preprocessing,'badchans') % Can exist if preloaded badchans.
                            app.data.preprocessing.badchans = [];
                        %end
                    end
                    app.data.savepath = savepath;
                    
                    % 2. RUN OPERATIONS WHICH USER CHECKED
                    
                    wrapper = @(x) strcmp(x,'Decimate');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
                        
                        selectedButton = app.SelectinputButtonGroup.SelectedObject.Text;
                        
                        switch selectedButton
                            case 'OutputFs'
                                
                                n=app.NtapsEditField_3.Value
                                wantedfs=app.OutputFsEditField.Value
                                d_factor = app.data.fsample/wantedfs;
                                if rem(d_factor,1)~=0
                                    error('Downsampling factor not integrer')
                                end
                                
                            case 'DFactor'
                                
                                d_factor=app.DownsamplingfactorEditField.Value;
                                n=app.NtapsEditField.Value;
                        end
                        
                        disp('Decimating..');
                        disp(sprintf('New srate: %i Hz', app.data.fsample / d_factor))
                        
                        if d_factor ~= 1 % Skip if fs = fs_new
                            craw=num2cell(app.data.trial{1},2);
                            wrapper = @(x) decimate(x,d_factor,n,'fir');
                            craw=cellfun(wrapper,craw,'UniformOutput',0);
                            app.data.trial{1}=cell2mat(craw);
                            app.data.fsample = app.data.fsample/d_factor
                            app.data.time = {[1:1:size(app.data.trial{1},2)]/app.data.fsample};
                        end
                        
                    end
                    
                    
                    wrapper = @(x) strcmp(x,'HP-filter');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
                        disp('HP-filtering..')
                        Fc=app.FcEditField.Value;
                        N=app.NtapsEditField_2.Value;
                        Fs = app.data.fsample;  % Sampling Frequency
                        flag = 'scale';  % Sampling Flag
                        % Create the window vector for the design algorithm.
                        win = hamming(N+1);
                        % Calculate the coefficients using the FIR1 function.
                        b  = fir1(N, Fc/(Fs/2), 'high', win, flag);
                        Hd = dfilt.dffir(b);
                        hp=filtfilt(Hd.Numerator,1,app.data.trial{1}')';
                        app.data.trial{1}=hp;
                    end
                    
                    wrapper = @(x) strcmp(x,'Remove bad CH');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))                      
                        if ~isempty(app.data.preprocessing.badchans)
                            c2rm=app.data.preprocessing.badchans;
                            badch2save = app.data.trial{1}(c2rm,:);
                            app.data.trial{1}(c2rm,:)=nan;
                            disp('Removing bad channels..')
                            app.data.badchannelsaved=badch2save;
                        else
                            disp('No bad channels to remove..')
                        end            
                    end


                    wrapper = @(x) strcmp(x,'Reference');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
                        disp('Referencing..')
                        goodchaninds = app.data.eeginds';
                        goodchaninds(app.data.preprocessing.badchans)=nan;goodchaninds=goodchaninds(~isnan(goodchaninds)); % leave out bad chans
                        
                        switch app.ChoosenewreferenceButtonGroup.SelectedObject.Text
                            
                            case 'Common ref'
                                new_ref = app.ElectrodenumberSpinner.Value;
                                
                            case 'Common average'
                                cfg = [];
                                cfg.channel = goodchaninds;
                                cfg.demean     = 'yes';
                                cfg.detrend    = 'no';
                                cfg.reref      = 'yes';
                                cfg.refchannel = 'all'; % NOTE LEAVE OUT INITIAL REF OR NOT??
                                cfg.refmethod  = 'avg';
                                cfg.implicitref = cell2mat(app.data.label(str2num(app.OldreferenceTextArea.Value{1}))); % add old reference label 'str'
                                
                                
                            case 'Linked electrodes'
                                cfg = []; % LINKED MASTOID reference
                                linked_channel = [app.ElectrodenumberSpinner_2.Value,app.ElectrodenumberSpinner_3.Value];
                                cfg.refchannel = {sprintf('E%i',linked_channel(1)),sprintf('E%i',linked_channel(2))};
                                % CHECK ARE IN BAD CHs!
                                cfg.channel = goodchaninds;
                                cfg.demean     = 'yes';
                                cfg.detrend    = 'no';
                                cfg.reref = 'yes';
                                cfg.refmethod = 'avg';
                                cfg.implicitref = cell2mat(app.data.label(str2num(app.OldreferenceTextArea.Value{1}))); % add old reference label 'str'
                                
                        end
                        
                        addpath 'fieldtrip-master/'
                        ft_defaults
                        data_refer = ft_preprocessing(cfg, app.data);
                        datadim=zeros(size(app.data.trial{1}));datadim(:,:)=nan;
                        %goodchaninds=find(~isnan(app.data.trial{1}(:,1)));
                        datai=datadim;
                        datai(goodchaninds,:)=data_refer.trial{1};
                        
                        if app.PlotresultsCheckBox_2.Value % do plotting
                            disp('plotting results..')
                            f=scroll_channels(detrend(app.data.trial{1}(app.data.eeginds',:)',0)',[-1000 1000],detrend(datai(app.data.eeginds',:)',0)')
                            waitfor(f)
                            % ADD LEGENDS???
                        end
                        app.data.trial{1}(app.data.eeginds',:) = datai(app.data.eeginds',:);
                        
                        
                    end
                    
                    
                    wrapper = @(x) strcmp(x,'Interpolate bad CH');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
                        
                        addpath 'fieldtrip-master/'
                        ft_defaults
                        
                        method = app.NeighbourapproachButtonGroup.SelectedObject.Text;
                        bad_ch = app.data.preprocessing.badchans;
                        
                        if ~isempty(bad_ch)
                            
                            if strcmp(app.NeighbourappButtonGroup.SelectedObject.Text,'re-calculate') % Calculate neighbours
                               
                                cfg= [];
                                cfg.method = 'distance'
                                %cfg.layout = 'GSN-HydroCel-257.mat'
                                elec = ft_read_sens('/home/tvayryne/egi_locs/eeglab257.sfp')%('GSN-HydroCel-257.sfp');
                                cfg.elec = ft_convert_units(elec, 'mm') % convert from cm to mm
                                cfg.feedback = 'no';
                                cfg.neighbourdist = app.NeighbourdistancemmSpinner.Value; % mm
                                neighbours = ft_prepare_neighbours(cfg, app.data);
                                
                            else % Load neighbours
                                switch length(app.data.eeginds)
                                    case 256
                                        load('/home/tvayryne/egi_locs/egi256_neighbours_pipet.mat');
                                        elec = ft_read_sens('/home/tvayryne/egi_locs/eeglab256.sfp');
                                    case 257
                                        load('/home/tvayryne/egi_locs/egi257_neighbours_pipet.mat');
                                        elec = ft_read_sens('/home/tvayryne/egi_locs/eeglab257.sfp');
                                end                             
                            end
                            
                            cfg= []
                            cfg.neighbours = neighbours;
                            cfg.elec = elec;
                            cfg.channels = app.data.eeginds';
                            cfg.method = app.NeighbourapproachButtonGroup.SelectedObject.Text
                            cfg.senstype = 'eeg'
                            [data] = ft_channelrepair(cfg, app.data) % FUNCTION CAN TREAT NANS AS BAD CH!
                            
                            if app.PlotresultsCheckBox.Value % do plotting
                                disp('plotting results..')
                                f=scroll_channels(detrend(data.trial{1}(bad_ch,:)',0)',[-1000 1000],detrend(app.data.badchannelsaved',0)')
                                waitfor(f)
                                % ADD LEGENDS???
                            end
                            app.data = data;
                        else
                           disp('No bad channels to interpolate') 
                        end
                    end
                    
                    
                         %%
                    wrapper = @(x) strcmp(x,'Despike');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
                        disp('Despiking..')
                        
                        [datao,cfig] = run_bad_segments(app.data);
                        
                        waitfor(cfig);
                        if ~isempty(data.preprocessing.shortsig)
                            data = gap_inpaint(data);
                        end
                        app.data = data;
                        
                    end
                    
                    %%
                    
                    wrapper = @(x) strcmp(x,'Remove trend');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
                        rmtrend = detrend(app.data.trial{1}',app.PolynomialdegreeEditField.Value)';
                        
                        if app.PlotresultsCheckBox_3.Value % do plotting
                            disp('plotting results..')
                            f=scroll_channels(rmtrend,[-10000 10000],app.data.trial{1})
                            waitfor(f)
                            %d ADD LEGENDS???
                        end
                        app.data.trial{1}=rmtrend;
                    end
                    
                    wrapper = @(x) strcmp(x,'ICA');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
%                         
%                         addpath('FastICA/')
%                         dm = 'off'
%                         if app.PlotprogressCheckBox.Value % graphical estimate
%                             dm = 'signals'
%                         end
%                         mixedsig=app.data.trial{1}(app.data.eeginds,:); % LEAVE OUT AUX CHANS.
%                         [icasig,A,W] = fastica (mixedsig, 'lastEig', app.LasteigenvalueEditField.Value, 'numOfIC', app.NumberofICEditField.Value,'approach',app.ApproachDropDown.Value,...
%                             'g',app.NonlinearityDropDown.Value,'displayMode',dm);
%                         app.data.ICA.ICs = icasig;
%                         app.data.ICA.mixingmA = A;
%                         app.data.ICA.separmW = W;
%                         % Separating matrix W, mixing matrix A.
%                         
%                         if app.PlotresultsCheckBox_4.Value % do plotting
%                             disp('plotting results..')
%                             f=scroll_channels(detrend(icasig',0)',[-50 50])
%                             waitfor(f)
%                             %d ADD LEGENDS???
%                         end
%                         
                        %% Fieldtrip implementation, better visualization
                        
                        addpath fieldtrip-master/
                        ft_defaults
                        dm = 'off';
                        if app.PlotprogressCheckBox.Value % graphical estimate progress
                            dm = 'signals';
                        end
                        cfg = [];
                        cfg.channel = [1:length(app.data.eeginds)]
                        cfg.fastica.approach = app.ApproachDropDown.Value; % Ei splittaa komponentteja niinku symmetric.
                        cfg.fastica.numOfIC = app.NumberofICEditField.Value;
                        cfg.fastica.g = app.NonlinearityDropDown.Value;
                        %   cfg.fastica.finetune
                        %   cfg.fastica.a1
                        %   cfg.fastica.a2
                        %   cfg.fastica.mu
                        %   cfg.fastica.stabilization
                        %   cfg.fastica.epsilon
                        %   cfg.fastica.maxNumIterations
                        %   cfg.fastica.maxFinetune
                        %   cfg.fastica.sampleSize
                        %   cfg.fastica.initGuess
                        %   cfg.fastica.verbose
                        cfg.fastica.displayMode = dm;
                        %   cfg.fastica.displayInterval
                        %   cfg.fastica.firstEig
                        cfg.fastica.lastEig = app.LasteigenvalueEditField.Value; % reduce dimension
                        %   cfg.fastica.interactivePCA
                        %   cfg.fastica.pcaE
                        %   cfg.fastica.pcaD
                        %   cfg.fastica.whiteSig
                        %   cfg.fastica.whiteMat
                        %   cfg.fastica.dewhiteMat
                        %   cfg.fastica.only
                        cfg.method = 'fastica';
                        comp = ft_componentanalysis(cfg, app.data);
     
                        if app.PlotresultsCheckBox_4.Value % do plotting
                            
                            % prepare layout
                            tcfg=[]
                            tcfg.layout = '/home/tvayryne/fieldtrip-master/template/layout/GSN-HydroCel-257.mat';
                            layout = ft_prepare_layout(tcfg);
                            
                            disp('plotting results..');
                            cfg = [];
                            cfg.colormap = jet(100);
                            cfg.layout = '/home/tvayryne/fieldtrip-master/template/layout/GSN-HydroCel-257.mat';
                            cfg.viewmode = 'component';
                            ft_databrowser(cfg, comp)
                            f=get(gcf)
                            waitfor(f)
                        end
                        app.data.comp = comp;


                    end
                    
                    
                    wrapper = @(x) strcmp(x,'Remove bad IC');
                    ischecked=cellfun(wrapper,operations,'UniformOutput',0);
                    if sum(cell2mat(ischecked))
                        
                        ic2reject=componentbrowser_PIPET(app.data);
                        waitfor(gcf)
                        if ~isempty(ic2reject)
                            ic2reject = cellfun(@(x) str2num(x),(strsplit(ic2reject,',')'))'
                            ic.artifact = sort(ic2reject);
                            cfg = [];
                            cfg.component = ic.artifact;
                            disp(sprintf('Removing %i ICs..',length(ic2reject)))
                            pruned = ft_rejectcomponent(cfg, app.data.comp, app.data);
                            
                            if app.PlotresultsCheckBox_5.Value
                                f=scroll_channels(detrend(pruned.trial{1}',0)',[-800 800], detrend(app.data.trial{1}',0)')
                                waitfor(f);
                            end
                            
                        else
                            disp('Removing 0 ICs')
                            pruned = data;
                            close all
                        end


                    end


                    %% SAVE
                if length(app.datalist)==1
                    disp('Saving dataset..')
                    data = app.data;
                    save(app.data.savepath,'data');
                    app.Lamp.Color = [0 1 0];
                    disp('Done')
                else
                    disp('Saving dataset..')
                    splits = strsplit(app.data.savepath,'/');
                    path = join(splits(1:end-1),'/');fname=strsplit(app.data.fname,'.dat');fname=fname{1};
                    data = app.data;
                    save(join([path{1} '/' fname '_' splits{end}]), 'data');
                    disp('Done')

                end


                end
            catch
                
                disp('Error..')
                
            end
            
            app.Lamp.Color=[0 1 0];


        end

        % Button pushed function: FINDButton
        function FINDButtonPushed(app, event)
            % FIND BAD CHANNELS FOR FILE(S)
            if isempty(app.datalist)
                warndlg('Load file(s) first')
            else
                
                for i = 1:length(app.datalist)
                    app.data = app.datalist(i);
    %                 % FIND BAD CHANNELS
    %                 disp('PRESS QUIT AFTER DONE')
    %                 cfg=[];
    %                 cfg.channel = [1:257]
    %                 cfg.continuous = 'yes'
    %                 cfg.detrend = 'yes'
    %                 cfg.method = 'channel'          % one channels at once
    %                 cfg.alim     = 5000;
    %                 cfg.keepchannel='nan'           % append nans to bad channels
    %                 rejected_data=ft_rejectvisual(cfg,app.data);
                    
                    addpath fieldtrip-master/
                    ft_defaults
                    
                    % 1st Use fieltrip to detect bad quickly
                    cfg = [];              
                    cfg.length = 10;
                    comp_segments = ft_redefinetrial(cfg, app.data); % Split continuous data into segments  

                    cfg          = [];
                    cfg.keepchannel='nan';
                    cfg.method   = 'summary';
                    cfg.channel = app.data.eeginds';
                    cfg.ylim     = [-1 1];
                    
                    switch length(app.data.eeginds)
                        case 256
                            load('/home/tvayryne/egi_locs/egi256_neighbours_pipet.mat')
                        case 257
                            load('/home/tvayryne/egi_locs/egi257_neighbours_pipet.mat')                   
                    end
                    cfg.neighbours = neighbours;            
                    dummy = ft_rejectvisual(cfg,comp_segments);
                    dummy.cfg.badchannel;
                    badch=find(isnan(dummy.trial{1}(app.data.eeginds,1))); 
                    
                    % Scroll channels trough
                    badch=scroll_artificial_channels2(detrend(app.data.trial{1}',1)',[-500 500],badch); % Remove linear trend for visualization
                    
                    app.datalist(i).preprocessing.badchans=badch;
                    
                    
                    % Lastly butterfly plot to confirm
    %                 current_choices=find_bad_butterfly(detrend(rejected_data.trial{1}(1:257,:)',1)');
    %                 rejected_data.trial{1}(current_choices,:)=NaN; % APPENDS NANS TO BAD CHANNELS
    %                 app.data.preprocessing.badchans=find(isnan(rejected_data.trial{1,1}(:,1)));
                    if ~isempty(app.datalist(i).preprocessing.badchans)
                        app.TextArea.Value = sprintf('Bad CHs: %s',join(string(app.datalist(i).preprocessing.badchans)',','))
                    else
                        app.TextArea.Value = sprintf('Bad CHs: none')
                    end
                    badchlist{i}= badch;


                end
                
                if app.SaveintoseparatefileforlaterloadingCheckBox.Value
                    if length(app.datalist)~=1
                        filter = {'*.mat'};
                        [file, path] = uiputfile(filter,'Save file as',['groupname_badchan_info' '.mat']); 
                        save([path file],"badchlist")
                        disp('Bad channels saved')
                    else
                        disp('Not filelist, not saving..');
                    end
                    
                end


            end

        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            
            if isempty(app.data)
                warndlg('Load file(s) first')
            else
                
                
                % All frequency values are in Hz.
                Fs = app.data.fsample;  % Sampling Frequency
                N = app.NtapsEditField.Value;      % Order
                Fc = 1/app.DownsamplingfactorEditField.Value; % Cutoff Frequency
                flag = 'scale';  % Sampling Flag
                win = hamming(N+1);
                % Calculate the coefficients using the FIR1 function.
                b  = fir1(N, Fc/(Fs/2), 'low', win, flag);
                Hd = dfilt.dffir(b);
                freqz(Hd,N*5,'whole',Fs)
    
                %plot example of decimation
                dsig=decimate(detrend(app.data.trial{1}(1,:),0),app.DownsamplingfactorEditField.Value,app.NtapsEditField.Value,'fir');
                figure;
                plot(app.data.time{1},detrend(app.data.trial{1}(1,:),0),'Color','k')
                hold on
                stem(app.data.time{1}(1:app.DownsamplingfactorEditField.Value:end),dsig,'ro','filled','markersize',4)
    
                legend('Original','Decimated')
                xlabel('Time')
                ylabel('Amplitude')
            end


        end

        % Button pushed function: DesignButton_2
        function DesignButton_2Pushed(app, event)
            
            if isempty(app.datalist)
                warndlg('Load file(s) first')
            else

                d_factor=app.DownsamplingfactorEditField.Value; % Decimation factor: WE ASSUME THAT USERS DOES DECIMATION BEFORE HP

                Fc=app.FcEditField.Value;
                N=app.NtapsEditField_2.Value;
                Fs = app.datalist(1).fsample/d_factor;  % Sampling Frequency
                flag = 'scale';  % Sampling Flag
                % Create the window vector for the design algorithm.
                win = hamming(N+1);
                % Calculate the coefficients using the FIR1 function.
                b  = fir1(N, Fc/(Fs/2), 'high', win, flag);
                Hd = dfilt.dffir(b);
                freqz(Hd,N*5,'whole',Fs)

            
%                 hp=filtfilt(Hd.Numerator,1,app.datalist(1).trial{1}(1,:));
%                 scroll_channels(app.datalist(1).trial{1}(1,:),[-1000 1000],hp)
                %                 %plot example of filtering
                %                 dsig=filtfilt(Hd.Numerator,1,app.data.trial{1}(1,:));
                %                 figure;
                %                 plot(app.data.time{1},detrend(app.data.trial{1}(1,:),0),'Color','k')
                %                 hold on
                %                 stem(app.data.time{1}(1:app.DownsamplingfactorEditField.Value:end),dsig,'ro','filled','markersize',4)
                %
                %                 legend('Original','Decimated')
                %                 xlabel('Time')
                %                 ylabel('Amplitude')


            end


        end

        % Value changed function: DownsamplingfactorEditField
        function DownsamplingfactorEditFieldValueChanged(app, event)
            value = app.DownsamplingfactorEditField.Value;
            newfsample = app.data.fsample / value;
           if ~isempty(app.data)
            app.TextArea.Value = {sprintf('Old srate= %i Hz \nResulting srate = %i Hz',app.data.fsample,newfsample)}        
           end


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            addpath fieldtrip-master/
            ft_defaults
            cfg= [];
            cfg.method = 'distance'
            %cfg.layout = 'GSN-HydroCel-257.mat'
            elec = ft_read_sens('/home/tvayryne/egi_locs/eeglab257.sfp')%('GSN-HydroCel-257.sfp');
            cfg.elec = ft_convert_units(elec, 'mm') % convert from cm to mm
            cfg.feedback = 'yes';
            cfg.neighbourdist = app.NeighbourdistancemmSpinner.Value; % mm
            neighbours = ft_prepare_neighbours(cfg);            
            
        end

        % Menu selected function: LoadfilelistMenu
        function LoadfilelistMenuSelected(app, event)
            
            addpath fieldtrip-master/
            ft_defaults
            
            app.Lamp.Color=[1 0 0]
            [file,path,index]=uigetfile('*.txt','Choose file');


            if file ~= 0
            
                fid = fopen([path file]);
                filesstr=textscan(fid,'%s','Delimiter','\n');filesstr=filesstr{1}
                fclose(fid)
                disp('Loading files into memory..')
                for e = 1:length(filesstr)
                    try
                        [var,srate] = load_eeg(filesstr{e,1});
                        varlist{e} = var;
                        sratelist(e) = srate;                
                    catch
                        continue
                    end
                end
                % SAVE FILENAMES!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                
    %             [raw,srate]= load_eeg([path file]);
    %             app.data=initialize_vars_ft(raw,srate)
    %             app.data.eeginds = find(~strcmp(app.data.label,'ECG'));  % CW?!?!?!?
    %             app.TextArea.Value = {sprintf('Current srate= %i Hz, Data dimensions: %i, %i, AUX channels: %i ',app.data.fsample, size(app.data.trial{1},1),size(app.data.trial{1},2), size(app.data.trial{1},1)-length(app.data.eeginds))} 
    %             app.OldreferenceTextArea.Value = string(find(~app.data.trial{1}(:,1)))             
    %             % ICA editfields
    %             app.LasteigenvalueEditField.Value = length(app.data.eeginds);
    %             app.NumberofICEditField.Value = length(app.data.eeginds);
    
                f = msgbox([sprintf('Loaded %i/%i files into memory',sum(~cellfun(@(x) isempty(x),varlist)),length(varlist)),newline,...
                    strcat(string(cellfun(@(x) size(x,1),varlist))','x',string(cellfun(@(x) size(x,2),varlist))',' Fs:',string(sratelist)',"  ",string(filesstr(1:length(varlist))))'])
    
                disp('Initializing variables..')
                sratelist = num2cell(sratelist);
                indices2keep = cellfun(@(x) ~isempty(x),varlist);
                varlist=varlist(find(indices2keep));
                sratelist=sratelist(find(indices2keep));            
                filesstr = filesstr(find(indices2keep))
                wrapper = @(x) strsplit(x,'/')
                filesstr = cellfun(wrapper,filesstr,'UniformOutput',false);
                filesstr = cellfun(@(x) x(end),filesstr)
    
                app.datalist=cellfun(@initialize_varlist_ft,varlist,sratelist,filesstr')
                            
    
                %fname = strsplit(file,'.dat');fname=fname{1};
                %app.data.fname = fname;           
                
                
                app.LOADButton.Enable=1;    
                app.SaveintoseparatefileforlaterloadingCheckBox.Enable=1;
                app.Lamp.Color=[0 1 0]


    %                                  app.Lamp.Color=[1 0 0]
    %             [file,path,index]=uigetfile('*.dat','Choose file','/DATA/multimodal/EEG/');
    %             [raw,srate]= load_eeg([path file]);
    %             app.data=initialize_vars_ft(raw,srate)
    %             app.data.eeginds = find(~strcmp(app.data.label,'ECG'));  % CW?!?!?!?
    %             app.TextArea.Value = {sprintf('Current srate= %i Hz, Data dimensions: %i, %i, AUX channels: %i ',app.data.fsample, size(app.data.trial{1},1),size(app.data.trial{1},2), size(app.data.trial{1},1)-length(app.data.eeginds))} 
    %             app.OldreferenceTextArea.Value = string(find(~app.data.trial{1}(:,1)))             
    %             % ICA editfields
    %             app.LasteigenvalueEditField.Value = length(app.data.eeginds);
    %             app.NumberofICEditField.Value = length(app.data.eeginds);
    %          
    %             app.Lamp.Color=[0 1 0]
            else
                app.Lamp.Color = [0 1 0];
            end


        end

        % Selection changed function: SelectinputButtonGroup
        function SelectinputButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectinputButtonGroup.SelectedObject.Text;

            switch selectedButton
                case 'OutputFs'
                    app.DownsamplingfactorEditField.Enable = 'off';
                    app.NtapsEditField.Enable = 'off';
                    app.NtapsEditField_3.Enable = 'on';
                    app.OutputFsEditField.Enable = 'on';
                case 'DFactor'
                    app.DownsamplingfactorEditField.Enable = 'on';
                    app.NtapsEditField.Enable = 'on';
                    app.NtapsEditField_3.Enable = 'off';
                    app.OutputFsEditField.Enable = 'off';
            end
            
            
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            
             [file,path,index]=uigetfile('*.mat','Choose file');
             load([path file])
             if length(badchlist) == length(app.datalist)
                 for i = 1:length(app.datalist)
                     app.datalist(i).preprocessing.badchans=badchlist{i};
                 end
                 disp('Bad channels loaded..')
                 app.FINDButton.Enable=0;
                 app.LOADButton.Enable=0;
                 app.TextArea.Value = {'Bad channel info loaded for filelist.'};
             else
                 error('Dimensions do not match with filelist')
             end


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Load files with computed ICs'
                    app.Tree.Enable=0;
                    app.RunButton.Enable=0;
                    app.LOADButton_2.Enable =1;
                case 'Remove as calculated'
                    app.Tree.Enable = 1;
                    app.RunButton.Enable=1;
                    app.LOADButton_2.Enable =0;
            end
            
            
        end

        % Button pushed function: LOADButton_2
        function LOADButton_2Pushed(app, event)
            
            app.Lamp.Color=[1 0 0];
            [file,path,index]=uigetfile('*.mat','Choose files','MultiSelect','on');
            listoffiles = strcat(path,file);
            if ~iscell(listoffiles)
             listoffiles={listoffiles};   
            end
            datalist=cellfun(@(x) load(x), listoffiles);
            
            datalist = squeeze(struct2cell(datalist));
            pruned = cellfun(@(x) do_ica_prune(x),datalist,'UniformOutput',0);
            
            % save ica pruned
            for i = 1:length(pruned)
                data = pruned{i};
                fname = strsplit(data.fname,'.dat');fname=fname{1};
                splits = strsplit(data.savepath,'/');
                groupname = strsplit(splits{end},'.');groupname = groupname{1};
                path = join(splits(1:end-1),'/');
                save(strcat(string(path), "/", string(fname), '_', groupname, '_ICA_pruned.mat'), 'data')
                disp(sprintf('Saved: %s',strcat(string(path), "/", string(fname), '_', groupname, '_ICA_pruned.mat')));
            end        
            app.Lamp.Color=[0 1 0];
            
            
            function pruned = do_ica_prune(data)
 
             
                ic2reject=componentbrowser_PIPET(data);
                waitfor(gcf)
                if ~isempty(ic2reject)
                    ic2reject = cellfun(@(x) str2num(x),(strsplit(ic2reject,',')'))'
                    ic.artifact = ic2reject;
                    cfg = [];
                    cfg.component = ic.artifact;
                    disp(sprintf('Removing %i ICs..',length(ic2reject)))
                    pruned = ft_rejectcomponent(cfg, data.comp, data);
    
                    if app.PlotresultsCheckBox_5.Value
                        f=scroll_channels(detrend(pruned.trial{1}',0)',[-800 800], detrend(data.trial{1}',0)')
                        waitfor(f);                 
                    end
                    
                else
                    disp('Removing 0 ICs')
                    pruned = data;
                    close all
                end
                
                
            end


        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            % PLOT CHOSEN DATASET
            list = string({app.datalist.fname}');
            [ind2plot,tf]=listdlg('ListString',list,'PromptString','Choose file to plot:','SelectionMode','single','ListSize',[500 200]);          
            if tf
                f=scroll_channels(detrend(app.datalist(ind2plot).trial{1}',1)',[-500 500]);disp('Removing linear trend for plotting..')       
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.LoadfileMenu = uimenu(app.ImportMenu);
            app.LoadfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfileMenuSelected, true);
            app.LoadfileMenu.Text = 'Load file';
            app.LoadfilelistMenu = uimenu(app.ImportMenu);
            app.LoadfilelistMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfilelistMenuSelected, true);
            app.LoadfilelistMenu.Text = 'Load filelist';
            app.Panel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DecimateTab = uitab(app.TabGroup);
            app.DecimateTab.Title = 'Decimate';
            app.DownsamplingfactorEditFieldLabel = uilabel(app.DecimateTab);
            app.DownsamplingfactorEditFieldLabel.Text = 'Downsampling factor';
            app.DownsamplingfactorEditField = uieditfield(app.DecimateTab, 'numeric');
            app.DownsamplingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @DownsamplingfactorEditFieldValueChanged, true);
            app.DownsamplingfactorEditField.Value = 5;
            app.NtapsEditFieldLabel = uilabel(app.DecimateTab);
            app.NtapsEditFieldLabel.Text = 'N taps';
            app.NtapsEditField = uieditfield(app.DecimateTab, 'numeric');
            app.NtapsEditField.Value = 100;
            app.DesignButton = uibutton(app.DecimateTab, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Tooltip = {'View filter response (currently only dfactor )'};
            app.DesignButton.Text = 'Design';
            app.OutputFsEditFieldLabel = uilabel(app.DecimateTab);
            app.OutputFsEditFieldLabel.Text = 'Output Fs';
            app.OutputFsEditField = uieditfield(app.DecimateTab, 'numeric');
            app.OutputFsEditField.Enable = 'off';
            app.OutputFsEditField.Value = 250;
            app.SelectinputButtonGroup = uibuttongroup(app.DecimateTab);
            app.SelectinputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectinputButtonGroupSelectionChanged, true);
            app.SelectinputButtonGroup.Tooltip = {'Uses fir1 (Hamming tapering) to desing lowpass filter with Fc = 1/d.factor'; ''; 'n.taps should be high enough to supress freqs above srate/dfactor'; ''; 'Fs/Fs_new needs to be integrer!'; ''};
            app.SelectinputButtonGroup.Title = 'Select input';
            app.DFactorButton = uiradiobutton(app.SelectinputButtonGroup);
            app.DFactorButton.Text = 'DFactor';
            app.DFactorButton.Value = true;
            app.OutputFsButton = uiradiobutton(app.SelectinputButtonGroup);
            app.OutputFsButton.Text = 'OutputFs';
            app.NtapsEditField_3Label = uilabel(app.DecimateTab);
            app.NtapsEditField_3Label.Text = 'N taps';
            app.NtapsEditField_3 = uieditfield(app.DecimateTab, 'numeric');
            app.NtapsEditField_3.Enable = 'off';
            app.NtapsEditField_3.Value = 100;
            app.HPfilterTab = uitab(app.TabGroup);
            app.HPfilterTab.Title = 'HP-filter';
            app.FcEditFieldLabel = uilabel(app.HPfilterTab);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.HPfilterTab, 'numeric');
            app.FcEditField.Value = 1;
            app.NtapsEditField_2Label = uilabel(app.HPfilterTab);
            app.NtapsEditField_2Label.Text = 'N taps';
            app.NtapsEditField_2 = uieditfield(app.HPfilterTab, 'numeric');
            app.NtapsEditField_2.Value = 90;
            app.DesignButton_2 = uibutton(app.HPfilterTab, 'push');
            app.DesignButton_2.ButtonPushedFcn = createCallbackFcn(app, @DesignButton_2Pushed, true);
            app.DesignButton_2.Tooltip = {'View filter response'; 'AFTER DECIMATION'; ''; 'Note: this is done after decimation -> Fs = Fs / d_factor.'; 'Adjust d_factor from ''Decimate'' tab. '; 'This affects filter frequency response, which depends on Fs'};
            app.DesignButton_2.Text = 'Design';
            app.DesignworksonlywithdfactordecimationLabel = uilabel(app.HPfilterTab);
            app.DesignworksonlywithdfactordecimationLabel.Text = 'Design works only with dfactor decimation';
            app.RemovebadCHTab = uitab(app.TabGroup);
            app.RemovebadCHTab.Title = 'Remove bad CH';
            app.FINDButton = uibutton(app.RemovebadCHTab, 'push');
            app.FINDButton.ButtonPushedFcn = createCallbackFcn(app, @FINDButtonPushed, true);
            app.FINDButton.Text = 'FIND';
            app.LOADButton = uibutton(app.RemovebadCHTab, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Enable = 'off';
            app.LOADButton.Text = 'LOAD';
            app.FindbadchannelsforfilesLabel = uilabel(app.RemovebadCHTab);
            app.FindbadchannelsforfilesLabel.Text = 'Find bad channels for file(s)';
            app.LoadtablewithbadCHinfoLabel = uilabel(app.RemovebadCHTab);
            app.LoadtablewithbadCHinfoLabel.Text = 'Load table with bad CH info';
            app.SaveintoseparatefileforlaterloadingCheckBox = uicheckbox(app.RemovebadCHTab);
            app.SaveintoseparatefileforlaterloadingCheckBox.Enable = 'off';
            app.SaveintoseparatefileforlaterloadingCheckBox.Text = 'Save into separate file for later loading';
            app.ORLabel = uilabel(app.RemovebadCHTab);
            app.ORLabel.Text = 'OR';
            app.ReferenceTab = uitab(app.TabGroup);
            app.ReferenceTab.Title = 'Reference';
            app.ChoosenewreferenceButtonGroup = uibuttongroup(app.ReferenceTab);
            app.ChoosenewreferenceButtonGroup.Title = 'Choose new reference';
            app.CommonrefButton = uiradiobutton(app.ChoosenewreferenceButtonGroup);
            app.CommonrefButton.Enable = 'off';
            app.CommonrefButton.Text = 'Common ref';
            app.CommonaverageButton = uiradiobutton(app.ChoosenewreferenceButtonGroup);
            app.CommonaverageButton.Text = 'Common average';
            app.CommonaverageButton.Value = true;
            app.LinkedelectrodesButton = uiradiobutton(app.ChoosenewreferenceButtonGroup);
            app.LinkedelectrodesButton.Text = 'Linked electrodes';
            app.ElectrodenumberSpinnerLabel = uilabel(app.ChoosenewreferenceButtonGroup);
            app.ElectrodenumberSpinnerLabel.Text = 'Electrode number';
            app.ElectrodenumberSpinner = uispinner(app.ChoosenewreferenceButtonGroup);
            app.ElectrodenumberSpinner.Enable = 'off';
            app.ElectrodenumberSpinner_2 = uispinner(app.ChoosenewreferenceButtonGroup);
            app.ElectrodenumberSpinner_2.Value = 94;
            app.ElectrodenumberSpinner_3 = uispinner(app.ChoosenewreferenceButtonGroup);
            app.ElectrodenumberSpinner_3.Value = 190;
            app.defmastoidLabel = uilabel(app.ChoosenewreferenceButtonGroup);
            app.defmastoidLabel.Text = '(def mastoid)';
            app.PlotButton_3 = uibutton(app.ChoosenewreferenceButtonGroup, 'push');
            app.PlotButton_3.Text = 'Plot';
            app.OldreferenceTextAreaLabel = uilabel(app.ReferenceTab);
            app.OldreferenceTextAreaLabel.Text = 'Old reference';
            app.OldreferenceTextArea = uitextarea(app.ReferenceTab);
            app.PlotresultsCheckBox_2 = uicheckbox(app.ReferenceTab);
            app.PlotresultsCheckBox_2.Text = 'Plot results';
            app.InterpolatebadCHTab = uitab(app.TabGroup);
            app.InterpolatebadCHTab.Title = 'Interpolate bad CH';
            app.NeighbourapproachButtonGroup = uibuttongroup(app.InterpolatebadCHTab);
            app.NeighbourapproachButtonGroup.Title = 'Neighbour approach';
            app.weightedButton = uiradiobutton(app.NeighbourapproachButtonGroup);
            app.weightedButton.Text = 'weighted';
            app.weightedButton.Value = true;
            app.averageButton = uiradiobutton(app.NeighbourapproachButtonGroup);
            app.averageButton.Text = 'average';
            app.splineButton = uiradiobutton(app.NeighbourapproachButtonGroup);
            app.splineButton.Text = 'spline';
            app.slapButton = uiradiobutton(app.NeighbourapproachButtonGroup);
            app.slapButton.Text = 'slap';
            app.sphericalLabel = uilabel(app.NeighbourapproachButtonGroup);
            app.sphericalLabel.Text = '(spherical)';
            app.surfacelaplacianLabel = uilabel(app.NeighbourapproachButtonGroup);
            app.surfacelaplacianLabel.Text = '(surface laplacian)';
            app.Label = uilabel(app.InterpolatebadCHTab);
            app.Label.Text = {'Note:  interpolation reduces rank by N of interpolated channels'; '(not spherical spline?)'};
            app.NeighbourappButtonGroup = uibuttongroup(app.InterpolatebadCHTab);
            app.NeighbourappButtonGroup.Title = 'Neighbour app.';
            app.UseprecalculatedforegionlyButton = uiradiobutton(app.NeighbourappButtonGroup);
            app.UseprecalculatedforegionlyButton.Text = 'Use pre-calculated (for egi only)';
            app.UseprecalculatedforegionlyButton.Value = true;
            app.recalculateButton = uiradiobutton(app.NeighbourappButtonGroup);
            app.recalculateButton.Text = 're-calculate';
            app.NeighbourdistancemmSpinnerLabel = uilabel(app.NeighbourappButtonGroup);
            app.NeighbourdistancemmSpinnerLabel.Text = 'Neighbour distance (mm)';
            app.NeighbourdistancemmSpinner = uispinner(app.NeighbourappButtonGroup);
            app.NeighbourdistancemmSpinner.Value = 30;
            app.PlotButton = uibutton(app.NeighbourappButtonGroup, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotresultsCheckBox = uicheckbox(app.InterpolatebadCHTab);
            app.PlotresultsCheckBox.Text = 'Plot results';
            app.DespikeTab = uitab(app.TabGroup);
            app.DespikeTab.Title = 'Despike';
            app.ThisstepshouldberunseparatelyLabel = uilabel(app.DespikeTab);
            app.ThisstepshouldberunseparatelyLabel.Text = 'This step should be run separately.';
            app.Label_2 = uilabel(app.DespikeTab);
            app.Label_2.Text = {'Removes artifactual spikes from optimal point by minimizing the baseline difference.'; 'Perform surrogate injection using surrogate data similar to inpainting.'; ''; 'User options:'; 'Z-score threshold, with adjustable separating resolution.'; 'Kurtosis threshold'; 'Adjustable buffer length (Button to be added)'; 'Ratio for rejection with bypass option.'; 'Number of windows used in Z-score calculation'; ''; ''; ''};
            app.RemovetrendTab = uitab(app.TabGroup);
            app.RemovetrendTab.Title = 'Remove trend';
            app.PolynomialdegreeEditFieldLabel = uilabel(app.RemovetrendTab);
            app.PolynomialdegreeEditFieldLabel.Text = 'Polynomial degree';
            app.PolynomialdegreeEditField = uieditfield(app.RemovetrendTab, 'numeric');
            app.meanremoval1lineartrendLabel = uilabel(app.RemovetrendTab);
            app.meanremoval1lineartrendLabel.Text = '0: mean removal, 1 linear trend...';
            app.PlotresultsCheckBox_3 = uicheckbox(app.RemovetrendTab);
            app.PlotresultsCheckBox_3.Text = 'Plot results';
            app.ICATab = uitab(app.TabGroup);
            app.ICATab.Title = 'ICA';
            app.NumberofICEditFieldLabel = uilabel(app.ICATab);
            app.NumberofICEditFieldLabel.Text = 'Number of IC';
            app.NumberofICEditField = uieditfield(app.ICATab, 'numeric');
            app.NumberofICEditField.Tooltip = {'Number of ICs to estimate. Max : dimension/rank of the data'};
            app.LasteigenvalueEditFieldLabel = uilabel(app.ICATab);
            app.LasteigenvalueEditFieldLabel.Text = 'Last eigenvalue';
            app.LasteigenvalueEditField = uieditfield(app.ICATab, 'numeric');
            app.LasteigenvalueEditField.Tooltip = {'Used for PCA dimension reduction = last eigenvalue retained'};
            app.ApproachDropDownLabel = uilabel(app.ICATab);
            app.ApproachDropDownLabel.Text = 'Approach';
            app.ApproachDropDown = uidropdown(app.ICATab);
            app.ApproachDropDown.Items = {'defl', 'symm'};
            app.ApproachDropDown.Tooltip = {'Decorrelation approach. Deflation estimates ICs one-by-one like'; 'Projection pursuit. Symmetric estimates components in parallel'};
            app.ApproachDropDown.Value = 'defl';
            app.NonlinearityDropDownLabel = uilabel(app.ICATab);
            app.NonlinearityDropDownLabel.Text = 'Nonlinearity';
            app.NonlinearityDropDown = uidropdown(app.ICATab);
            app.NonlinearityDropDown.Items = {'pow3', 'tanh', 'gauss', 'skew'};
            app.NonlinearityDropDown.Value = 'pow3';
            app.PlotresultsCheckBox_4 = uicheckbox(app.ICATab);
            app.PlotresultsCheckBox_4.Text = 'Plot results';
            app.NoteifdataranknotadjustedghostingofICswillhappenLabel = uilabel(app.ICATab);
            app.NoteifdataranknotadjustedghostingofICswillhappenLabel.Text = 'Note: if data rank not adjusted, ghosting of ICs will happen';
            app.PlotprogressCheckBox = uicheckbox(app.ICATab);
            app.PlotprogressCheckBox.Enable = 'off';
            app.PlotprogressCheckBox.Text = 'Plot progress';
            app.RemovebadICTab = uitab(app.TabGroup);
            app.RemovebadICTab.Title = 'Remove bad IC';
            app.ButtonGroup = uibuttongroup(app.RemovebadICTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RemoveascalculatedButton = uiradiobutton(app.ButtonGroup);
            app.RemoveascalculatedButton.Text = 'Remove as calculated';
            app.RemoveascalculatedButton.Value = true;
            app.LoadfileswithcomputedICsButton = uiradiobutton(app.ButtonGroup);
            app.LoadfileswithcomputedICsButton.Text = 'Load files with computed ICs';
            app.LOADButton_2 = uibutton(app.ButtonGroup, 'push');
            app.LOADButton_2.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_2Pushed, true);
            app.LOADButton_2.Text = 'LOAD';
            app.PlotresultsCheckBox_5 = uicheckbox(app.RemovebadICTab);
            app.PlotresultsCheckBox_5.Text = 'Plot results';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.Tree = uitree(app.UIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.Tooltip = {'Calculations are performed in the same order as in the tree list'};
            app.DecimateNode = uitreenode(app.Tree);
            app.DecimateNode.Text = 'Decimate';
            app.HPfilterNode = uitreenode(app.Tree);
            app.HPfilterNode.Text = 'HP-filter';
            app.RemovebadCHNode = uitreenode(app.Tree);
            app.RemovebadCHNode.Text = 'Remove bad CH';
            app.ReferenceNode_2 = uitreenode(app.Tree);
            app.ReferenceNode_2.Text = 'Reference';
            app.InterpolatebadCHNode = uitreenode(app.Tree);
            app.InterpolatebadCHNode.Text = 'Interpolate bad CH';
            app.DespikeNode = uitreenode(app.Tree);
            app.DespikeNode.Text = 'Despike';
            app.RemovetrendNode = uitreenode(app.Tree);
            app.RemovetrendNode.Text = 'Remove trend';
            app.ICANode = uitreenode(app.Tree);
            app.ICANode.Text = 'ICA';
            app.RemovebadICNode = uitreenode(app.Tree);
            app.RemovebadICNode.Text = 'Remove bad IC';
            app.ChooseoperationsLabel = uilabel(app.UIFigure);
            app.ChooseoperationsLabel.Text = 'Choose operations:';
            app.PlotButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.MultiselectholdctrlLabel = uilabel(app.UIFigure);
            app.MultiselectholdctrlLabel.Text = 'Multiselect (hold ctrl)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIPET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end